classdef layerSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1        matlab.ui.Figure
        uipanel2       matlab.ui.container.Panel
        cancel         matlab.ui.control.Button
        ok             matlab.ui.control.Button
        layerName      matlab.ui.control.Label
        text1          matlab.ui.control.Label
        uipanel1       matlab.ui.container.Panel
        edit_text5     matlab.ui.control.Label
        edit5          matlab.ui.control.EditField
        checkbox5      matlab.ui.control.CheckBox
        edit_text4     matlab.ui.control.Label
        edit4          matlab.ui.control.EditField
        checkbox4      matlab.ui.control.CheckBox
        edit_text3     matlab.ui.control.Label
        edit_text2     matlab.ui.control.Label
        edit_text1     matlab.ui.control.Label
        edit3          matlab.ui.control.EditField
        edit2          matlab.ui.control.EditField
        edit1          matlab.ui.control.EditField
        path1          matlab.ui.control.EditField
        change         matlab.ui.control.Button
        trialList      matlab.ui.control.DropDown
        checkbox1      matlab.ui.control.CheckBox
        checkbox2      matlab.ui.control.CheckBox
        checkbox3      matlab.ui.control.CheckBox
        globalSetting  matlab.ui.control.CheckBox
        lable1         matlab.ui.control.Label
    end

    
    properties (Access = private)
        currentTrial = 1 % Description
    end
    
    methods (Access = private)
        function setValues(app)
            % Set Changes user made to layer settings
            layersettings = getappdata(gcf, 'tmpSettings');
            trialNo  = app.currentTrial;
            selectedConfig = layersettings(trialNo);

            % Global settings
            for n = 1:length(layersettings)
                layersettings(n).global = app.globalSetting.Value;
            end
            
            % Apply path settings
            if ~strcmp(selectedConfig.path{1}, 'OFF')
                selectedConfig.path{2} = app.path1.Value;
            end

            % Apply box settings
            index = 1;
            while ~strcmp(selectedConfig.box{index}{1}, 'OFF')
                eval("selectedConfig.box{" + index + "}{2} = app.checkbox" + index + ".Value;")
                index = index + 1;
            end

            % Apply text settings
            index = 1;
            while ~strcmp(selectedConfig.edit{index}{1}, 'OFF')
                eval("selectedConfig.edit{" + index + "}{2} = app.edit" + index + ".Value;")
                index = index + 1;
            end
            clear index
            
            setappdata(gcf, 'tmpSettings', selectedConfig);
        end
        
        function updateFigure(app)
            % Updates gui to show only relevant settings
            layerSettings = getappdata(gcf, 'tmpSettings');
            trialNo = app.currentTrial;
            selectedConfig = layerSettings(trialNo);

            % Turn off all settings by default until we read layer settings
            resetVisibility(app);
            
            % Set drop down list of all trial names
            C = length(layerSettings);
            trialNames = cell(1,C);
            
            for c = 1:C
                trialNames{c} = ['Trial ' num2str(c)];
                if c == trialNo
                    % Tell user which trial is selected
                    trialNames{c} = strcat(trialNames{c}, '*');
                end
            end
            app.trialList.Items = trialNames;
            
            % Global setting
            app.globalSetting.Value = selectedConfig.global;
            
            % Path settings
            if ~strcmp(selectedConfig.path{1}, 'OFF')
                app.lable1.Text = selectedConfig.path{1};
                app.path1.Enable = 'On';
                app.path1.Value = selectedConfig.path{2};
                app.change.Text = 'On';
            end
            
            % Box settings
            index = 1;
            while ~strcmp(selectedConfig.box{index}{1}, 'OFF')
                eval("app.checkbox" + index + ".Visible = 'On';")
                eval("app.checkbox" + index + ".Text  = selectedConfig.box{index}{1};")
                eval("app.checkbox" + index + ".Value   = selectedConfig.box{index}{2};")
                index = index + 1;
            end

            % Text settings
            index = 1;
            while ~strcmp(selectedConfig.edit{index}{1}, 'OFF')
                eval("app.edit"      + index + ".Visible = 'On';")
                eval("app.edit_text" + index + ".Visible = 'On';")
                eval("app.edit"      + index + ".Value   = selectedConfig.edit{index}{1};")
                eval("app.edit_text" + index + ".Text    = selectedConfig.edit{index}{2};")
                index = index + 1;
            end
        end

        function resetVisibility(app)
            % Turns off and hides settings components until we know we need them
            app.path1.Enable = "off";
            app.change.Enable = "off";
            % Turn off checkbox1 ... checkbox5 etc.
            for index = 1:5
                eval("app.edit" + num2str(index) + ".visible = 'off';")
                eval("app.edit_text" + num2str(index) + ".visible = 'off';")
                eval("app.checkbox" + num2str(index) + ".visible = 'off';")
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function settings_OpeningFcn(app, varargin)
            % --- Executes just before settings is made visible.
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            %Get current settings
            navData   = getappdata(0, 'navData');
            chstimuli = getappdata(0, 'chstimuli');
            index     = navData.activeStim;
            z         = navData.marker(index);
            layer = chstimuli(index).layers(z);
            tmpSettings = layer.settings;

            % Set layer name
            app.layerName.Text = layer.name;
            
            setappdata(gcf, 'tmpSettings', tmpSettings);
            updateFigure(app);
        end

        % Button pushed function: cancel
        function cancel_Callback(app, event)
            % --- Executes on button press in cancel.
            close(gcf);
        end

        % Button pushed function: change
        function change_Callback(app, event)
            % --- Executes on button press in change.
            % Get current settings
            navData   = getappdata(0, 'navData');
            chstimuli = getappdata(0, 'chstimuli');
            index     = navData.activeStim;
            z         = navData.marker(index); % Chosen layer
            k         = app.currentTrial;
            layer     = chstimuli(index).layers(z);
            
            if length(layer.settings(k).path1) > 2
                FilterSpec = layer.settings(k).path1{3};
                [FileName,PathName,~] = uigetfile(FilterSpec);
            else
                [FileName,PathName,~] = uigetfile();
            end
            
            if FileName ~= 0
                set(handles.path1, 'String', [PathName FileName]);
            end
            
            setValues(app);
            updateFigure(app);
        end

        % Button pushed function: ok
        function ok_Callback(app, event)
            % --- Executes on button press in ok.
            % Save current values
            setValues(app);
            
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            lSettings = getappdata(gcf, 'tmpSettings');
            index = navData.activeStim;
            z     = navData.marker(index);
            
            %save new settings
            chstimuli(index).layers(z).settings = lSettings;
            
            setappdata(0, 'chstimuli', chstimuli);
            close(gcf);
        end

        % Value changed function: trialList
        function trialList_Callback(app, event)
            % --- Executes on selection change in trialList.
            newTrial = app.trialList.Value; %index of chosen trial
            % Save previous settings and update GUI
            setValues(app);
            app.currentTrial = newTrial;
            updateFigure(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'settings';
            app.figure1.Tag = 'figure1';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Tag = 'uipanel2';
            app.uipanel1 = uipanel(app.uipanel2);
            app.uipanel1.Title = 'Trial Settings';
            app.uipanel1.Tag = 'uipanel1';
            app.lable1 = uilabel(app.uipanel1);
            app.lable1.Tag = 'lable1';
            app.lable1.Text = 'Static Text';
            app.globalSetting = uicheckbox(app.uipanel1);
            app.globalSetting.Tag = 'globalSetting';
            app.globalSetting.Text = 'Global Setting';
            app.checkbox3 = uicheckbox(app.uipanel1);
            app.checkbox3.Tag = 'checkbox3';
            app.checkbox3.Text = 'Setting';
            app.checkbox2 = uicheckbox(app.uipanel1);
            app.checkbox2.Tag = 'checkbox2';
            app.checkbox2.Text = 'Setting';
            app.checkbox1 = uicheckbox(app.uipanel1);
            app.checkbox1.Tag = 'checkbox1';
            app.checkbox1.Text = 'Setting';
            app.trialList = uidropdown(app.uipanel1);
            app.trialList.Items = {'Pop-up Menu'};
            app.trialList.ValueChangedFcn = createCallbackFcn(app, @trialList_Callback, true);
            app.trialList.Tag = 'trialList';
            app.trialList.Value = 'Pop-up Menu';
            app.change = uibutton(app.uipanel1, 'push');
            app.change.ButtonPushedFcn = createCallbackFcn(app, @change_Callback, true);
            app.change.Tag = 'change';
            app.change.Text = 'Change';
            app.path1 = uieditfield(app.uipanel1, 'text');
            app.path1.Tag = 'path1';
            app.path1.Value = 'Edit Text';
            app.edit1 = uieditfield(app.uipanel1, 'text');
            app.edit1.Tag = 'edit1';
            app.edit1.Value = '100';
            app.edit2 = uieditfield(app.uipanel1, 'text');
            app.edit2.Tag = 'edit2';
            app.edit2.Value = '100';
            app.edit3 = uieditfield(app.uipanel1, 'text');
            app.edit3.Tag = 'edit3';
            app.edit3.Value = '100';
            app.edit_text1 = uilabel(app.uipanel1);
            app.edit_text1.Tag = 'edit_text1';
            app.edit_text1.Text = 'Setting';
            app.edit_text2 = uilabel(app.uipanel1);
            app.edit_text2.Tag = 'edit_text2';
            app.edit_text2.Text = 'Setting';
            app.edit_text3 = uilabel(app.uipanel1);
            app.edit_text3.Tag = 'edit_text3';
            app.edit_text3.Text = 'Setting';
            app.checkbox4 = uicheckbox(app.uipanel1);
            app.checkbox4.Tag = 'checkbox4';
            app.checkbox4.Text = 'Setting';
            app.edit4 = uieditfield(app.uipanel1, 'text');
            app.edit4.Tag = 'edit4';
            app.edit4.Value = '100';
            app.edit_text4 = uilabel(app.uipanel1);
            app.edit_text4.Tag = 'edit_text4';
            app.edit_text4.Text = 'Setting';
            app.checkbox5 = uicheckbox(app.uipanel1);
            app.checkbox5.Tag = 'checkbox5';
            app.checkbox5.Text = 'Setting';
            app.edit5 = uieditfield(app.uipanel1, 'text');
            app.edit5.Tag = 'edit5';
            app.edit5.Value = '100';
            app.edit_text5 = uilabel(app.uipanel1);
            app.edit_text5.Tag = 'edit_text5';
            app.edit_text5.Text = 'Setting';
            app.text1 = uilabel(app.uipanel2);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Layer settings';
            app.layerName = uilabel(app.uipanel2);
            app.layerName.Tag = 'layerName';
            app.layerName.Text = 'Layer Name';
            app.ok = uibutton(app.uipanel2, 'push');
            app.ok.ButtonPushedFcn = createCallbackFcn(app, @ok_Callback, true);
            app.ok.Tag = 'ok';
            app.ok.Text = 'OK';
            app.cancel = uibutton(app.uipanel2, 'push');
            app.cancel.ButtonPushedFcn = createCallbackFcn(app, @cancel_Callback, true);
            app.cancel.Tag = 'cancel';
            app.cancel.Text = 'Cancel';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = layerSettings(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)settings_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end