classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1     matlab.ui.Figure
        File        matlab.ui.container.Menu
        new         matlab.ui.container.Menu
        open        matlab.ui.container.Menu
        save_as     matlab.ui.container.Menu
        importStim  matlab.ui.container.Menu
        exit        matlab.ui.container.Menu
        tools       matlab.ui.container.Menu
        settings    matlab.ui.container.Menu
        rename      matlab.ui.control.Button
        copy        matlab.ui.control.Button
        title       matlab.ui.control.Label
        test        matlab.ui.control.Button
        killScreen  matlab.ui.control.Button
        initScreen  matlab.ui.control.Button
        text2       matlab.ui.control.Label
        text1       matlab.ui.control.Label
        remove      matlab.ui.control.Button
        moveDown    matlab.ui.control.Button
        moveUp      matlab.ui.control.Button
        listbox2    matlab.ui.control.ListBox
        listbox1    matlab.ui.control.ListBox
        next        matlab.ui.control.Button
    end

    
    methods (Access = private)
        function gotoStim(~, stimNo)
            
            %load data
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            
            %update
            if length(chstimuli) >= stimNo %should always be the case
            
                navData.activeStim = stimNo;                     %update index
                setappdata(0, 'navData', navData);               %save navData
            
                %switch gui
                nextGui  = chstimuli(stimNo).fileNameGui;
                delete(gcf) %close current gui
            
                eval(nextGui); %start new gui
            end
        end
        
        function updateFigure(app)
            
            %Read appdata
            chstimuli  = getappdata(0, 'chstimuli');
            navData    = getappdata(0, 'navData');
            screenData = getappdata(0, 'screenData');
            
            %Update listboxes
            names_avstimuli = getLayer('List');
            names_chstimuli = {chstimuli.name};
            
            app.listbox1.Items = names_avstimuli;
            app.listbox2.Items = names_chstimuli;
            
            %Update the next/previous buttons
            navFcn(app, 'Update');
            
            %Update kill/init screen buttons
            if screenData.isInit
                app.initScreen.Enable = 'Off'; %grey out init button
                app.killScreen.Enable = 'On';  %enable kill button
                app.test.Enable = 'Off';        %enable test button
            else
                app.initScreen.Enable = 'On';   %grey out init button
                app.killScreen.Enable = 'Off';  %disable kill button
                app.test.Enable = 'on';        %disable test button
            end
            
            updateIndexes(app)

            %Title - Update the title so that it is correct.
            app.figure1.Name = navData.fileName;
        end
            
        function updateIndexes(app)
            app.listbox2.ItemsData = 1:length(app.listbox2.Items);
            app.listbox1.ItemsData = 1:length(app.listbox1.Items);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function main_OpeningFcn(app, varargin)
            % --- Executes just before main is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            addpath(cd, [cd '/' genpath('Subfunctions')], [cd '/' genpath('Stimuli')]); %Add subfuntions in separate folder
            
            %Initialize
            navFcn(app, 'AddHandle'); %save the handle (in root)
            updateFigure(app);              % Populate the listbox
            app.listbox1.Value = 1;    % Default selection
            
            %set position of window
            navData   = getappdata(0, 'navData');
            
            screenRes = get(0, 'ScreenSize');   %[1 1 screenWidth screenHeight]
            
            set(gcf, 'Units' , 'Pixels');
            posdata = get(gcf,'Position');
            height = posdata(4);
            width = posdata(3);
            set(gcf, 'Position', [navData.windowPosition (screenRes(4)-height)/2 width height]);
        end

        % Button pushed function: copy
        function copy_Callback(app, event)
            % --- Executes on button press in copy.
            % Get selected index of listbox2
            index     = app.listbox2.Value;          %to be copied
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            
            %check if there are any objects to remove and disallow removing main
            if ~isempty(chstimuli) && index ~= 1
                N = length(chstimuli) - index;  %number of entries needs to move
            
                for n = 0:N+1
                    chstimuli(end+1-n) = chstimuli(end-n);
                end
            
                navData.numStim = navData.numStim + 1; %update number of stims in list
            
                setappdata(0, 'chstimuli', chstimuli); %Save changes
                setappdata(0, 'navData',   navData);
            
            
                navData.marker = navData.marker([1:index index:end]);
                updateIndexes(app)
                updateFigure(app);
            end
            
            setappdata(0, 'navData', navData);
        end

        % Menu selected function: exit
        function exit_Callback(app, event)
            % --------------------------------------------------------------------
            close(gcf);
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            % --- Executes when user attempts to close figure1.
            % Hint: delete(hObject) closes the figure
            
            navFcn(app, 'Close');
            delete(app.figure1);
        end

        % Menu selected function: importStim
        function importStim_Callback(app, event)
            % --------------------------------------------------------------------
            menuFcn('importStim');
            updateFigure(app);
        end

        % Button pushed function: initScreen
        function initScreen_Callback(app, event)
            % --- Executes on button press in initScreen.
            screenFcn('Init');
            updateFigure(app);
        end

        % Button pushed function: killScreen
        function killScreen_Callback(app, event)
            % --- Executes on button press in killScreen.
            
            screenFcn('Kill');
            updateFigure(app);
        end

        % Double-clicked callback: listbox1
        function listbox1DoubleClicked(app, event)
                chstimuli = getappdata(0, 'chstimuli');
                navData   = getappdata(0, 'navData');
            
                index     = app.listbox1.Value;     % Get index of selected object
                layer     = app.listbox1.Items{index};
                new_layer = getLayer(layer);        % Chosen layer to start with
            
                write_index = length(chstimuli) + 1; % Index to write to (last in list)
            
                %make new stimuli
                new_entry.name          = convertStringsToChars(new_layer.name);
                new_entry.fileNameGui   = 'tableGui';
                new_entry.settingsGui   = 'settingsGui';
                new_entry.hGui          = 0;
                new_entry.layers        = new_layer;
                new_entry.targetBgColor = 255;
                new_entry.stimSettings  = 0;
            
                chstimuli(write_index)  = new_entry; % Add to array
                navData.numStim         = navData.numStim + 1; % Update number of stims in list
                navData.marker          = [navData.marker 1];
            
                setappdata(0, 'chstimuli', chstimuli); % save changes
                setappdata(0, 'navData',   navData);   % save changes
                currentList = app.listbox2.Items;
                currentList{end + 1} = new_entry.name;
                app.listbox2.Items = currentList;
                updateIndexes(app)
                app.listbox2.Value = write_index; % update marker
                updateFigure(app); %update
        end

        % Double-clicked callback: listbox2
        function listbox2DoubleClicked(app, event)
                index = app.listbox2.Value;  %get index of selected object
                gotoStim(app, index);
        end

        % Button pushed function: moveDown
        function moveDown_Callback(app, event)
            % --- Executes on button press in moveDown.
            %get selected index of listbox2
            index = app.listbox2.Value;
            chstimuli = getappdata(0, 'chstimuli');
            
            if index < length(chstimuli) && index~= 1
                %move down if possible, keep main at top
            
                tmp = chstimuli(index);
                chstimuli(index) = chstimuli(index+1);
                chstimuli(index+1) = tmp;
                setappdata(0, 'chstimuli', chstimuli);
            
                app.listbox2.Value = index + 1;
            
                updateIndexes(app)
                updateFigure(app);
            end
        end

        % Button pushed function: moveUp
        function moveUp_Callback(app, event)
            % --- Executes on button press in moveUp.
            %get selected index of listbox2
            index     = app.listbox2.Value;
            chstimuli = getappdata(0, 'chstimuli');
            if index > 2 %over 2, move up (Keep main at top)
            
                tmp = chstimuli(index);
                chstimuli(index)   = chstimuli(index-1);
                chstimuli(index-1) = tmp;
                setappdata(0, 'chstimuli', chstimuli);
            
                app.listbox2.Value = index - 1;
            
                updateIndexes(app)
                updateFigure(app);
            end
        end

        % Menu selected function: new
        function new_Callback(app, event)
            % --------------------------------------------------------------------
            menuFcn('New');
        end

        % Button pushed function: next
        function next_Callback(app, event)
            % --- Executes on button press in next.
            % Load data
            navData   = getappdata(0, 'navData');
            
            index = navData.activeStim; % Current stim
            gotoStim(app, index+1);
        end

        % Menu selected function: open
        function open_Callback(app, event)
            % --------------------------------------------------------------------
            menuFcn('Open');
            
            navData = getappdata(0, 'navData');
            chstimuli = getappdata(0, 'chstimuli');
            navData.numStim = length(chstimuli); 
            navData.marker = ones(1, navData.numStim);
            navData.activeStim = 1;
            setappdata(0, 'navData', navData);
            
            updateFigure(app);
        end

        % Button pushed function: remove
        function remove_Callback(app, event)
            % --- Executes on button press in remove.
            %get selected index of listbox2
            index     = app.listbox2.Value; % To be removed
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            
            %check if there are any objects to remove and disallow removing main
            if isempty(chstimuli) || index == 1
                return
            end

            N = length(chstimuli) - index;  %number of entries needs to move
        
            for n = 0:N-1
                chstimuli(index+n) = chstimuli(index+n+1);
            end
        
            chstimuli = chstimuli(1:end-1); %remove last entry
            navData.numStim = navData.numStim - 1; %update number of stims in list
        
            setappdata(0, 'chstimuli', chstimuli); %Save changes
            setappdata(0, 'navData',   navData);
        
            if index > length(chstimuli)
                app.listbox2.Value = index - 1;  % Move up index
            end
        
            navData.marker = navData.marker([1:index-1 index+1:end]); % Remove marker at index
            updateIndexes(app)
            updateFigure(app);

            
            setappdata(0, 'navData', navData);
        end

        % Button pushed function: rename
        function rename_Callback(app, event)
            % --- Executes on button press in rename.
            %get selected index of listbox2
            index     = app.listbox2.Value;          %to be renamed
            chstimuli = getappdata(0, 'chstimuli');
            
            newName = inputdlg({'Enter new name: '});
            
            if ~isempty(newName)
                chstimuli(index).name = newName{1};
                setappdata(0, 'chstimuli', chstimuli);
                updateFigure(app);
            end
        end

        % Menu selected function: save_as
        function save_as_Callback(app, event)
            % --------------------------------------------------------------------
            menuFcn('SaveAs');
            updateFigure(app);
        end

        % Menu selected function: settings
        function settings_Callback(app, event)
            % --------------------------------------------------------------------
            mainSettings;
        end

        % Button pushed function: test
        function test_Callback(app, event)
            % --- Executes on button press in test.
            screenFcn('Init');
            
            pause(2);
            screenFcn('Kill');
            disp('Screen Closed');
            
            updateFigure(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'main';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.Tag = 'figure1';
            app.File = uimenu(app.figure1);
            app.File.Text = 'File';
            app.File.Tag = 'File';
            app.new = uimenu(app.File);
            app.new.MenuSelectedFcn = createCallbackFcn(app, @new_Callback, true);
            app.new.Text = 'New';
            app.new.Tag = 'new';
            app.open = uimenu(app.File);
            app.open.MenuSelectedFcn = createCallbackFcn(app, @open_Callback, true);
            app.open.Text = 'Open';
            app.open.Tag = 'open';
            app.save_as = uimenu(app.File);
            app.save_as.MenuSelectedFcn = createCallbackFcn(app, @save_as_Callback, true);
            app.save_as.Text = 'Save as';
            app.save_as.Tag = 'save_as';
            app.importStim = uimenu(app.File);
            app.importStim.MenuSelectedFcn = createCallbackFcn(app, @importStim_Callback, true);
            app.importStim.Text = 'Import Stimuli';
            app.importStim.Tag = 'importStim';
            app.exit = uimenu(app.File);
            app.exit.MenuSelectedFcn = createCallbackFcn(app, @exit_Callback, true);
            app.exit.Text = 'Exit';
            app.exit.Tag = 'exit';
            app.tools = uimenu(app.figure1);
            app.tools.Text = 'Tools';
            app.tools.Tag = 'tools';
            app.settings = uimenu(app.tools);
            app.settings.MenuSelectedFcn = createCallbackFcn(app, @settings_Callback, true);
            app.settings.Text = 'Settings';
            app.settings.Tag = 'settings';
            app.next = uibutton(app.figure1, 'push');
            app.next.ButtonPushedFcn = createCallbackFcn(app, @next_Callback, true);
            app.next.Tag = 'next';
            app.next.Text = 'Next';
            app.listbox1 = uilistbox(app.figure1);
            app.listbox1.Items = {};
            app.listbox1.Tag = 'listbox1';
            app.listbox1.DoubleClickedFcn = createCallbackFcn(app, @listbox1DoubleClicked, true);
            app.listbox1.Value = {};
            app.listbox2 = uilistbox(app.figure1);
            app.listbox2.Items = {};
            app.listbox2.Tag = 'listbox2';
            app.listbox2.DoubleClickedFcn = createCallbackFcn(app, @listbox2DoubleClicked, true);
            app.listbox2.Value = {};
            app.moveUp = uibutton(app.figure1, 'push');
            app.moveUp.ButtonPushedFcn = createCallbackFcn(app, @moveUp_Callback, true);
            app.moveUp.Tag = 'moveUp';
            app.moveUp.Text = 'Move Up';
            app.moveDown = uibutton(app.figure1, 'push');
            app.moveDown.ButtonPushedFcn = createCallbackFcn(app, @moveDown_Callback, true);
            app.moveDown.Tag = 'moveDown';
            app.moveDown.Text = 'Move Down';
            app.remove = uibutton(app.figure1, 'push');
            app.remove.ButtonPushedFcn = createCallbackFcn(app, @remove_Callback, true);
            app.remove.Text = 'Remove';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Available stimuli';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Chosen stimuli';
            app.initScreen = uibutton(app.figure1, 'push');
            app.initScreen.ButtonPushedFcn = createCallbackFcn(app, @initScreen_Callback, true);
            app.initScreen.Tag = 'initScreen';
            app.initScreen.Text = 'Init Screen';
            app.killScreen = uibutton(app.figure1, 'push');
            app.killScreen.ButtonPushedFcn = createCallbackFcn(app, @killScreen_Callback, true);
            app.killScreen.Tag = 'killScreen';
            app.killScreen.Text = 'Kill Screen';
            app.test = uibutton(app.figure1, 'push');
            app.test.ButtonPushedFcn = createCallbackFcn(app, @test_Callback, true);
            app.test.Tag = 'test';
            app.test.Text = 'Test';
            app.title = uilabel(app.figure1);
            app.title.Tag = 'title';
            app.title.Text = 'Main';
            app.copy = uibutton(app.figure1, 'push');
            app.copy.ButtonPushedFcn = createCallbackFcn(app, @copy_Callback, true);
            app.copy.Tag = 'copy';
            app.copy.Text = 'Copy';
            app.rename = uibutton(app.figure1, 'push');
            app.rename.ButtonPushedFcn = createCallbackFcn(app, @rename_Callback, true);
            app.rename.Tag = 'rename';
            app.rename.Text = 'Rename';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)main_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end