classdef tableGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        file                        matlab.ui.container.Menu
        new                         matlab.ui.container.Menu
        openFile                    matlab.ui.container.Menu
        saveAs                      matlab.ui.container.Menu
        importStim                  matlab.ui.container.Menu
        exit                        matlab.ui.container.Menu
        tools                       matlab.ui.container.Menu
        recordStimulus              matlab.ui.container.Menu
        avimovie                    matlab.ui.container.Menu
        imsequence                  matlab.ui.container.Menu
        settings                    matlab.ui.container.Menu
        TabGroup                    matlab.ui.container.TabGroup
        StandardtoolsTab            matlab.ui.container.Tab
        initBackground              matlab.ui.control.Button
        closeBackground             matlab.ui.control.Button
        uipanel2                    matlab.ui.container.Panel
        chosenRow                   matlab.ui.control.EditField
        rowLog                      matlab.ui.control.Button
        rowShuffle_1                matlab.ui.control.Button
        useValue1                   matlab.ui.control.Button
        chosenCol                   matlab.ui.control.EditField
        valNum                      matlab.ui.control.EditField
        shiftColLeft                matlab.ui.control.Button
        shiftColRight               matlab.ui.control.Button
        rowLin                      matlab.ui.control.Button
        text10                      matlab.ui.control.Label
        allRows                     matlab.ui.control.CheckBox
        uipanel1                    matlab.ui.container.Panel
        text5                       matlab.ui.control.Label
        numRuns                     matlab.ui.control.EditField
        numRunsOk                   matlab.ui.control.Button
        trialSubset                 matlab.ui.control.CheckBox
        trials                      matlab.ui.control.EditField
        AdvancedtoolsTab            matlab.ui.container.Tab
        SplitScreenexperimentalFeaturePanel  matlab.ui.container.Panel
        splitScreenDir              matlab.ui.control.DropDown
        SplitScreenDropDownLabel    matlab.ui.control.Label
        splitScreenPos              matlab.ui.control.NumericEditField
        SplitatpixelEditFieldLabel  matlab.ui.control.Label
        splitScreen                 matlab.ui.control.CheckBox
        uipanel18                   matlab.ui.container.Panel
        mseqButton                  matlab.ui.control.Button
        mseqChange                  matlab.ui.control.EditField
        mseqStart                   matlab.ui.control.EditField
        useCumsum                   matlab.ui.control.CheckBox
        uipanel22                   matlab.ui.container.Panel
        applyWhiteNoiseBtn          matlab.ui.control.Button
        gaussianWhiteNoiseVariance  matlab.ui.control.EditField
        text35                      matlab.ui.control.Label
        uipanel23                   matlab.ui.container.Panel
        generateXYbutton            matlab.ui.control.Button
        uitable1                    matlab.ui.control.Table
        uipanel16                   matlab.ui.container.Panel
        impulse                     matlab.ui.control.CheckBox
        stimSettings                matlab.ui.control.Button
        editLayerName               matlab.ui.control.Button
        layerList                   matlab.ui.control.DropDown
        openLayerManager            matlab.ui.control.Button
        layerName                   matlab.ui.control.Label
        editName                    matlab.ui.control.Button
        stimName                    matlab.ui.control.Label
        text17                      matlab.ui.control.Label
        resetMissed                 matlab.ui.control.Button
        info                        matlab.ui.control.Label
        uipanel13                   matlab.ui.container.Panel
        CancelButton                matlab.ui.control.Button
        text36                      matlab.ui.control.Label
        applyBgColour               matlab.ui.control.Button
        editBgColor                 matlab.ui.control.EditField
        stimList                    matlab.ui.control.DropDown
        clear                       matlab.ui.control.Button
        grid                        matlab.ui.control.Button
        killScreen                  matlab.ui.control.Button
        initScreen                  matlab.ui.control.Button
        run                         matlab.ui.control.Button
        next                        matlab.ui.control.Button
        previous                    matlab.ui.control.Button
    end

    methods (Access = private) 
        % Updates app data and GUI to reflect change in stimuli layer selection
        function gotoLayer(app, layerNO)
            % Get app data
            navData    = getappdata(0, 'navData');
            index      = navData.activeStim;
            z          = navData.marker(index);
            
            % Don't change if on current layer
            if z == layerNO
                return
            end

            navData.marker(index) = layerNO;   % Update index
            setappdata(0, 'navData', navData); % Save navData
            updateTable(app);                  % Update the GUI
        end

        % Updates app data and GUI to reflect change in stimuli selection
        function gotoStim(app, stimNO)
            % Get app data
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            index     = navData.activeStim;
            
            % Don't change if press on current stim or if last stim in list
            if index == stimNO || stimNO > length(chstimuli)
                return
            end

            navData.activeStim = stimNO;       % Update index
            setappdata(0, 'navData', navData); % Save navData
        
            % If the next gui belongs to the same template class we only change
            % the content of the gui. If the template differs we need to open
            % (eval) a new gui.
            if strcmp(chstimuli(index).fileNameGui, chstimuli(stimNO).fileNameGui)
                updateFigure(app);
            else
                nextGui  = chstimuli(stimNO).fileNameGui; % Switch gui
                delete(gcf)    % Close current gui
                eval(nextGui); % Start new gui
            end
        end
        
        function rowInterp(~, Row, Mode)
            % Interpolate row values from start to finish either
            % logarithmically or linearly

            % Get app data
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            % Get stimuli and layer indexes
            stimuliIndex = navData.activeStim;
            layerIndex   = navData.marker(stimuliIndex);
            % Get current layer data
            data         = chstimuli(stimuliIndex).layers(layerIndex).data;
            
            % Get all rows (R) and columns (C)
            [R, C]  = size(data);
            
            % Make sure row selection is in possible range
            if Row <= R && Row >= 1
                % Get first and last values to interpolate between
                firstVal = data(Row, 1);
                lastVal  = data(Row, C);
            
                % Choose either linear or log interpolation and do it over
                % all columns (trials)
                switch Mode
                    case 'LIN'
                        data(Row, 1:end) = linspace(firstVal, lastVal, C);
                    case 'LOG'
                        data(Row, 1:end) = logspace(log10(firstVal), log10(lastVal), C);
                    otherwise
                        % Error handling for incorrect settings
                        disp(['Incorrect case(rowInterp: ' Mode ')']);
                end
            
                % Save our new data
                chstimuli(stimuliIndex).layers(layerIndex).data = data;
                setappdata(0, 'chstimuli', chstimuli);
            end
        end
        
        function rowShuffle(~, Row)
            % Interpolate from start to stop values linearly
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            
            % Get current stimulus and layer
            stimulusIndex     = navData.activeStim;
            layerIndex        = navData.marker(stimulusIndex);
            
            % Get row data
            data   = chstimuli(stimulusIndex).layers(layerIndex).data;
            
            [R, ~] = size(data);
            % Make sure selected row actually exists
            if Row <= R && Row >= 1
                % Need to check where this function comes from - Jaxon
                data(Row,:) = Shuffle(data(Row,:));
                % Update and save app data after shuffle
                chstimuli(stimulusIndex).layers(layerIndex).data = data;
                setappdata(0, 'chstimuli', chstimuli);
            end
        end
        
        function shiftColumn(app, step)
            % Shift entire selected column by step
            
            % Get app data
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            stimuliIndex = navData.activeStim;
            layerIndex   = navData.marker(stimuliIndex);
            layerData    = chstimuli(stimuliIndex).layers(layerIndex).data;
            
            % Get current column and where to move it
            selectedCol = str2double(app.chosenCol.Value);
            moveToCol   = min(max(selectedCol + step, 1), C);
            
            % If the column would move to the same spot, cancel
            if moveToCol == selectedCol
                return
            end

            % Save column data so we don't lose it
            temp = layerData(:, moveToCol);
            % Swap data between the two columns
            layerData(:, moveToCol) = layerData(:, selectedCol);
            layerData(:, selectedCol) = temp;
        
            % Update app data and GUI
            chstimuli(stimuliIndex).layers(layerIndex).data = layerData;
            setappdata(0, 'chstimuli', chstimuli);
            app.chosenCol.Value = num2str(moveToCol);
            updateFigure(app);
        end
        
        function updateFigure(app)
            % Updates the GUI            
            navFcn(app, 'Update'); % Updates the next and run buttons
            updateTable(app);      % Update stimuli settings table
            drawnow;               % Force update GUI
        end
        
        function updateTable(app)
            % Executes when the tableGui is updated
            % Load app data
            navData    = getappdata(0, 'navData');
            screenData = getappdata(0, 'screenData');
            chstimuli  = getappdata(0, 'chstimuli');
            
            % Read parameters to set (default values of chstimuli(index))
            stimuliIndex = navData.activeStim;
            layersNO     = length(chstimuli(stimuliIndex).layers);
            layerIndex   = navData.marker(stimuliIndex);
            
            % Prevent indexing to out of bounds layers
            if layerIndex > layersNO
                layerIndex = layersNO;
                navData.marker(stimuliIndex) = layersNO; % If we lowered the number of objects
            end
            
            % Get current layer data and parameters
            data  = chstimuli(stimuliIndex).layers(layerIndex).data;
            names = chstimuli(stimuliIndex).layers(layerIndex).parameters;
            
            % Get number of columns (No. of trials)
            [~, C] = size(data);
            
            % Check or uncheck impulse
            app.impulse.Value = chstimuli(stimuliIndex).layers(layerIndex).impulse;

            %Set the table data
            app.uitable1.Data = data;
            
            %Set row names
            app.uitable1.RowName = names;
            
            %Make table editable
            columnEditable  = true(1, C);
            app.uitable1.ColumnEditable = columnEditable;
            
            % Update table format to match number of trials
            columnFormat = cell(1,C);
            columnFormat = cellfun(@(x) 'numeric', columnFormat, 'UniformOutput', false);
            app.uitable1.ColumnFormat = columnFormat;
            
            % Set columnnames and width
            columnName = cell(C, 1);
            columnWidth = cell(1, C);

            % Set trial names
            for c = 1:C
                columnName{c} = strcat('Trial_', num2str(c));
                columnWidth{c} = navData.colWidth;
            end
            app.uitable1.ColumnName = columnName;
            app.uitable1.ColumnWidth = columnWidth;
            
            % Set number of trials
            app.numRuns.Value = num2str(C);
            
            % Title - Update the GUI title so that it is correct.
            app.stimName.Text = chstimuli(stimuliIndex).name;
            
            % Set stimulus layername
            app.layerName.Text = ['Layer ' num2str(layerIndex) ':' chstimuli(stimuliIndex).layers(layerIndex).name];
            
            % Update project name
            app.figure1.Name = ['FlyFly 4.2: ' navData.fileName];
            
            % Set info label
            if screenData.isInit
                newInfo{1} = ['Screen: ' num2str(screenData.partial(3)) 'x' ...
                    num2str(screenData.partial(4)) ' px, ' ...
                    num2str(screenData.hz) ' Hz'];
            else
                newInfo{1} = 'Screen: Not initialized';
            end
            newInfo{2} = ['Skipped frames: ' num2str(navData.skippedLatest)];
            newInfo{3} = ['Skipped total: '  num2str(navData.skippedFrames)];
            
            app.info.Text = newInfo;
            
            % Set drop down list (stimuli)
            [~, C, ~] = size(chstimuli);
            stimOrder = cell(C, 1);
            for c = 1:C
                % Loop through stimuli and assign them to a cell array
                stimOrder{c} = [chstimuli(c).name];
                if c == navData.activeStim
                    stimOrder{c} = strcat(stimOrder{c}, '*');
                end
            end

            % Apply stimOrder to the GUI and add metadata for indexing
            app.stimList.Items = stimOrder;
            app.stimList.ItemsData = 1:C;
            app.stimList.Value = stimuliIndex;
            
            % Set drop down list (layer)
            [~, C, ~] = size(chstimuli(stimuliIndex).layers);
            layerOrder = cell(C, 1);
            for c = 1:C
                % Loop through layers and assign them to a cell array
                layerOrder{c} = [chstimuli(stimuliIndex).layers(c).name];
            
                if c == layerIndex
                    layerOrder{c} = strcat(layerOrder{c}, '*');
                end
            end
            % Apply layerOrder to the GUI and add metadata for indexing
            app.layerList.Items = layerOrder;
            app.layerList.ItemsData = 1:C;
            app.layerList.Value = layerIndex;
            
            % Initialise target bg colour if first time opening a stimuli
            if ~isfield(chstimuli(stimuliIndex), 'targetBgColor')
                chstimuli(stimuliIndex).targetBgColor = 255;
                setappdata(0, 'chstimuli', chstimuli);
            end
            app.editBgColor.Value = num2str(chstimuli(stimuliIndex).targetBgColor);

            % Disable "next" button if last stimuli
            if stimuliIndex == length(chstimuli)
                set(app.next, 'Visible', 'Off');
            else
                set(app.next, 'Visible', 'On');
            end
        end

        % Set component properties that require runtime configuration
        function addRuntimeConfigurations(app)
            app.uitable1.BackgroundColor = [1 1 1;0.9608 0.9608 0.9608];
            app.uitable1.ColumnFormat = {[] []};
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function tableGui_OpeningFcn(app, varargin)
            % Executes just before tableGui is made visible.
            % Add runtime required configuration
            addRuntimeConfigurations(app);

            % Prevent accidentally quitting out of an animation
            cancelInterrupt = true;
            setappdata(0, 'cancelInterrupt', cancelInterrupt);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Get app data
            navData   = getappdata(0, 'navData');
            screenData = getappdata(0, 'screenData');

            % Get info about splitScreen settings
            app.splitScreen.Value    = screenData.useSplitScreen;
            app.splitScreenDir.Value = screenData.splitDir;
            app.splitScreenPos.Value = screenData.splitPos;
            
            screenRes = get(0, 'ScreenSize'); % In format: [1 1 screenWidth screenHeight]
            
            % Set figure size relative to the current screen size
            set(gcf, 'Units' , 'Pixels');
            posdata = get(gcf,'Position');
            height = posdata(4);
            width = posdata(3);
            set(gcf, 'Position', [navData.windowPosition (screenRes(4)-height)/2 width height]);
            
            % Get path to flyfly
            flyflyPath = mfilename('fullpath');
            flyflyPath = erase(flyflyPath, 'tableGui');
            flyflyPath = flyflyPath + "Subfunctions";

            % Enable subfunctions
            addpath(flyflyPath)
            
            %Save handle to this figure
            navFcn(app, 'AddHandle');
            
            updateFigure(app);
        end

        % Button pushed function: applyBgColour
        function applyBgColour_Callback(app, event)
            % Applies background colour choice to psychtoolbox screen 
            
            % Get app data
            navData    = getappdata(0, 'navData');
            chstimuli  = getappdata(0, 'chstimuli');
            index    = navData.activeStim;
            screenData = getappdata(0, 'screenData');
            
            % Apply colour to open screen
            Screen('FillRect', screenData.wPtr,  chstimuli(index).targetBgColor);
            Screen('FillRect', screenData.wPtr, screenData.triggerRGBoff, screenData.triggerPos); %trigger off
            Screen('Flip', screenData.wPtr);

            % Update app settings
            screenData.bgColor = chstimuli(index).targetBgColor;
            setappdata(0, 'screenData', screenData);
        end

        % Button pushed function: applyWhiteNoiseBtn
        function applyWhiteNoiseBtn_Callback(app, event)
            % Applies gaussian white noise to all cell values in a row 
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
        
            % Get stimuli and layer indexes
            stimuliIndex    = navData.activeStim;
            layerIndex      = navData.marker(stimuliIndex);
            
            % Get layer data and amount of trials
            data   = chstimuli(stimuliIndex).layers(layerIndex).data;
            [~, C] = size(data);
        
            row = str2double(app.chosenRow.value);
        
            % Calculate guassian white noise
            variance = str2double(app.gaussianWhiteNoiseVariance.Value);
            noise_vector = pseudo_normal(0, variance, C);
        
            % Apply noise to all values in row
            data(row, 1:end) = data(row, 1:end) + noise_vector;
        
            % Update and save app data
            chstimuli(stimuliIndex).layers(layerIndex).data = data;
            setappdata(0, 'chstimuli', chstimuli);
            % Update GUI
            updateFigure(app);
        end

        % Button pushed function: clear
        function clear_Callback(app, event)
            % If screen is initialised, clear it and close it
            screenFcn('Clear');
            % Update status of screen and update GUI
            screenData = getappdata(0, 'screenData');
            screenData.inUse = 0;
            setappdata(0, 'screenData', screenData);
            updateFigure(app);
        end

        % Value changed function: editBgColor
        function editBgColor_Callback(app, event)
            % Get desired background colour as double
            tbgc = str2double(app.editBgColor.Value);
            % Find what stimulus we're changing
            navData    = getappdata(0, 'navData');
            index    = navData.activeStim;
            
            % Update set value and store it
            chstimuli  = getappdata(0, 'chstimuli');
            chstimuli(index).targetBgColor = tbgc;
            setappdata(0, 'chstimuli', chstimuli);
        end

        % Button pushed function: editLayerName
        function editLayerName_Callback(app, event)
            % Changes current layer name to user choice
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            % Get current stimuli index
            index    = navData.activeStim;
            
            % Open window for user to choose name
            newName = inputdlg({'Enter new layer name: '}, '', 1, {chstimuli(index).layers(navData.marker(index)).name});
            
            % Only continue if user has made a choice
            if ~isempty(newName)
                % Update app data
                chstimuli(index).layers(navData.marker(index)).name = newName{1};
                setappdata(0, 'chstimuli', chstimuli);
                % Update GUI with new name
                updateTable(app);
            end
        end

        % Button pushed function: editName
        function editName_Callback(app, event)
            % Changes name of current stimuli to user choice
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            % Get current stimuli index
            index     = navData.activeStim;
            
            % Opens window for user choice
            newName = inputdlg({'Enter new name: '}, '', 1, {chstimuli(index).name});
            
            % Cancel if user inputs nothing
            if ~isempty(newName)
                % Save new name and update GUI with it
                chstimuli(index).name = newName{1};
                setappdata(0, 'chstimuli', chstimuli);            
                updateTable(app);
            end
        end

        % Menu selected function: exit
        function exit_Callback(app, event)
            % Closes FlyFly via dedicated button
            navFcn(app, 'Close'); % clean up
            close(gcf);
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            % Cleans up figure and FlyFly when user quits            
            navFcn(app, 'Close'); % clean up
            delete(app.figure1);
        end

        % Button pushed function: generateXYbutton
        function generateXYbutton_Callback(app, event)
            % This needs to be reviewed as this may be an automated process
            % and should not require a dedicated button
            % --- Executes on button press in generateXYbutton.
        	screendata = getappdata(0, 'screenData');
            if screendata.ifi == 0
                warndlg('Please initialize screen first.');
            else
                chstimuli = getappdata(0, 'chstimuli');
                navData   = getappdata(0, 'navData');
                index     = navData.activeStim;
                z         = navData.marker(index);
                data      = chstimuli(index).layers(z).data;
        
                NUM_TRIALS = size(data, 2);
                SUBWIDTH = data(1, 1);
                SUBHEIGHT = data(2, 1);
                MIDX = data(3, 1);
                MIDY = data(4, 1);
                SPEED = data(9, 1);
                MOVE_DURATION = data(10,1)*screendata.ifi;
                TARGET_SIZE = 15;
        
                [data(5, :), data(6, :), data(7, :), data(8, :)] = ...
                    generateXY(NUM_TRIALS, SPEED, MOVE_DURATION, SUBWIDTH, SUBHEIGHT, MIDX, MIDY, TARGET_SIZE);
        
                chstimuli(index).layers(z).data = data;
                setappdata(0, 'chstimuli', chstimuli);
        
                updateFigure(app);
            end
        end

        % Button pushed function: grid
        function grid_Callback(app, event)
            % If screen is initialised, draw a grid on it
            screenData = getappdata(0, 'screenData');
            
            if screenData.isInit
                % Set up grid on stimuli screen
                screenFcn('DrawGrid');
                Screen(screenData.wPtr, 'Flip');
                % Update app data and GUI
                screenData.inUse = 0;
                setappdata(0, 'screenData', screenData);
                updateFigure(app);
            end
        end

        % Menu selected function: importStim
        function importStim_Callback(app, event)
            % Imports user choice of stimuli, likely to be deprecated 
            menuFcn('importStim');
            updateFigure(app);
        end

        % Value changed function: impulse
        function impulse_Callback(app, event)
            % When user changes value of 'impulse', update and save it in
            % app settings            
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            index     = navData.activeStim;
            z         = navData.marker(index);
            
            chstimuli(index).layers(z).impulse = event.Value;            
            setappdata(0, 'chstimuli', chstimuli);
        end

        % Menu selected function: imsequence
        function imsequence_Callback(app, event)
            % Generate the chosen stimuli as a series of images

            % Get app data
            navData      = getappdata(0, 'navData');
            screenData   = getappdata(0, 'screenData');
            chstimuli    = getappdata(0, 'chstimuli');
            % get chosen stimulus index
            stimuliIndex = navData.activeStim;
            
            % Proceed only if screen is initialised
            if ~screenData.isInit
                disp('Screen is not initialized');
                return
            end
            
            % Get number of trials
            [~, C]  = size(chstimuli(stimuliIndex).layers(1).data); %same number of trials in all layers
            
            % Cut down number of trials used if user requests
            if app.trialSubset.Value
                trialChoice = app.trials.Value;
                if isempty(trialChoice)
                    % If trial selection is empty, default to all trials
                    trialChoice = 1:C;
                else
                    trialChoice = eval(strrep(trialChoice,'end',num2str(C)));
                    % automatically clip values larger than the number of trials
                    trialChoice = min(C,trialChoice);
                end
            else
                % Otherwise, use all trials
                trialChoice = 1:C;
            end

            % Ask user which framerate to generate stimuli at
            fps = inputdlg({'Framerate to record in:'}, 'FPS', 1, {num2str(screenData.hz)});
            fps = str2double(fps{1});
            % Ask user for sequence name
            name = inputdlg({'Name sequence '}, 'Name', 1, {chstimuli(navData.activeStim).name});

            if isempty(name) % Empty if press on cancel
                disp('Image sequence NOT saved!');
                return
            end

            % Get directory to save to
            dir_name = uigetdir(cd,'Set target directory');
            if ~ischar(dir_name) % return if invalid
                disp('Image sequence NOT saved!');
                return
            end

            % Generate the image sequence (located in 'subfunctions')
            disp('Creating image sequence...')
            getStimImages(chstimuli(stimuliIndex), screenData, navData, trialChoice, fps, name, dir_name);

            fprintf('Sequence saved to %s!\n',dir_name);
        end

        % Menu selected function: avimovie
        function avimovie_Callback(app, event)
            % Generate a .avi movie of the stimulus instead of playing it

            % Get needed app data
            navData    = getappdata(0, 'navData');
            screenData = getappdata(0, 'screenData');
            chstimuli  = getappdata(0, 'chstimuli');
            
            % Get chosen stimuli
            index      = navData.activeStim;
            
            % Screen needs to be initialised first
            if ~screenData.isInit
                % If screen is not init, let user know nothing happened
                disp('Screen is not initialized');
                return
            end

            % Get number of trials
            [~, C]  = size(chstimuli(index).layers(1).data); % Same number of trials in all layers
            
            % Use only part of the total trials if selected
            if app.trialSubset.Value 
                trialChoice = app.trials.Value;
                % Prevent incorrect values
                if isempty(trialChoice)
                    trialChoice = 1:C;
                else
                    trialChoice = eval(strrep(trialChoice,'end',num2str(C)));
                    % Automatically clip values larger than the number of trials
                    trialChoice = min(C,trialChoice);
                end
            else
                % Use all trials if no preference
                trialChoice = 1:C;
            end
            
            % User input for what framerate to generate .avi movie in
            % (default is screens refresh rate)
            fps = inputdlg({'Framerate to record in:'}, 'FPS', 1, {num2str(screenData.hz)});
            fps = str2double(fps{1});

            % Ask user for sequence name
            name = inputdlg({'Name of video '}, 'Name', 1, {chstimuli(navData.activeStim).name});

            if isempty(name) % Empty if press on cancel
                disp('Stimulus movie NOT saved!');
                return
            end

            % Get directory to save movie to
            dir_name = uigetdir(cd,'Set target directory');
            if ~ischar(dir_name) % return if invalid
                disp('Stimulus movie NOT saved!');
                return
            end

            % Generate the image sequence (located in 'subfunctions')
            disp('Generating all frames...')
            outputName = getStimImages(chstimuli(index), screenData, navData, trialChoice, fps, name, dir_name);
            fprintf('Done! \nGenerating video from frames...\n')
            
            system(['ffmpeg -framerate ' convertStringsToChars(num2str(fps)) ' -i ' strrep(dir_name, ' ', '\ ') '/' strrep(outputName, ' ', '\ ') '_%05d.png ' strrep(dir_name, ' ', '\ ') '/' strrep(name{1}, ' ', '\ ') '.mp4'])
            fprintf('AVI file saved to %s! \nCleaning up the frames...\n',dir_name);
            imageFiles = dir([dir_name '/' outputName '*.png']);
            imageFiles = {imageFiles.name};
            for frame = 1:length(imageFiles)
                delete([dir_name '/' imageFiles{frame}])
            end
            disp('Done!')
        end

        % Button pushed function: initScreen
        function initScreen_Callback(app, event)
            % Initialise stimulus screen
            screenData = getappdata(0, 'screenData');
            
            % Run the init script
            screenFcn('Init');
            % Update app data
            app.killScreen.Enable = 'on';
            app.initScreen.Enable = 'off';

            % If using guvcview, initialise the application
            if screenData.useGuvcview
                path = which('initCam.sh');
                command = "bash " + path + " &"; % links to bash script that starts guvcview with video parameters 
                disp('Trying to Init Cam');
                [~, ~] = system(command, '-echo'); % Runs command in terminal
            end
            % Update the GUI
            updateFigure(app);
        end

        % Button pushed function: killScreen
        function killScreen_Callback(app, event)
            % Kills stimulus screen (if open)
            screenData = getappdata(0, 'screenData');
            
            % Activate the kill script
            screenFcn('Kill');
            % Update app data to reflect change
            app.killScreen.Enable = 'off';
            app.initScreen.Enable = 'on';

            % If user is recording, close guvcview window
            if screenData.useGuvcview
                command = "pkill guvcview"; % links to bash script that starts guvcview with video parameters 
                disp('Trying to close guvcview');
                [~, ~] = system(command, '-echo'); % Runs command in terminal
            end
            % Update GUI with changes
            updateFigure(app);
        end

        % Value changed function: layerList
        function layerList_Callback(app, event)
            % Switch current layer based on user selection from a list
            layer = event.Value; % Index of chosen layer
            gotoLayer(app, layer)
        end

        % Button pushed function: openLayerManager
        function openLayerManager_Callback(app, event)
            % Opens layer manager window, with a link to tableGUI elements
            layerManager(@app.updateTable); 
        end

        % Button pushed function: mseqButton
        function mseqButton_Callback(app, event)
            % Generates a 'Maximum Length Sequence' based on user input and
            % applies it to the selected row

            % Ask user what input values to use
            param = inputdlg({'Base value: ','N:', 'Shift:', 'Which sequence:'}, 'm-sequence', 1, {'2','6','1','1'});
            if ~isempty(param)
                % If user inputted values correctly, assign them to
                % variables as numbers
                baseVal = str2double(param{1});
                p       = str2double(param{2});
                shift   = str2double(param{3});
                which   = str2double(param{4});
                % Return if there's an error with the users input
                if ~isnumeric(baseVal) || ~isnumeric(p) || ~isnumeric(shift) || ~isnumeric(which)
                    disp('One or more values are not numeric!')
                    return
                end
                change = str2double(app.mseqChange.Value);
                % Generate the sequence using a matlab function
                sequence = change*mseq(baseVal,p,shift,which);
        
                % Get app data 
                chstimuli    = getappdata(0, 'chstimuli');
                navData      = getappdata(0, 'navData');
                stimuliIndex = navData.activeStim;
                layerIndex   = navData.marker(stimuliIndex);
                layerData    = chstimuli(stimuliIndex).layers(layerIndex).data;
                % Update layer name to include M-Seq generation details
                chstimuli(stimuliIndex).layers(layerIndex).name = ...
                    sprintf('%s (mseq: %d,%d,%d,%d)',chstimuli(stimuliIndex).layers(layerIndex).name,baseVal,p,shift,which);
                
                % Get layer info dimensions
                [~, C]  = size(layerData);
                row = str2double(app.chosenRow.Value);
                start = str2double(app.mseqStart.Value);

                % extend sequence if it is too short
                sequence = repmat(sequence,ceil(C/length(sequence)),1);

                % Apply sequence to data colums on its own or use a
                % cumalitive sum when adding values 
                if app.useCumsum.Value
                    layerData(row, 1:end) = start+cumsum(sequence(1:C));
                else
                    layerData(row, 1:end) = start+sequence(1:C);
                end
        
                % Update app data and GUI
                chstimuli(stimuliIndex).layers(layerIndex).data = layerData;
                setappdata(0, 'chstimuli', chstimuli);
                updateFigure(app);
           
            end
        end

        % Menu selected function: new
        function new_Callback(app, event)
            % Resets all stimulus data and user settings, starts a new
            % project
            menuFcn('New');
        end

        % Button pushed function: next
        function next_Callback(app, event)
            % Loads next stimuli in list and updates GUI
            navData   = getappdata(0, 'navData');
            index     = navData.activeStim;
            
            gotoStim(app, index+1);
        end

        % Button pushed function: numRunsOk
        function numRunsOk_Callback(app, event)
            % Updates the stimulus table with the selected amount of trials
            % to use

            % Get app data
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            
            % Get stimuli index and amount of trials to have
            stimuliIndex = navData.activeStim;
            userRuns     = abs(str2double(app.numRuns.Value)); 
            
            % Prevent incorrect values
            if isempty(userRuns)
                userRuns = 1;
            end
            % Get number of all layers (amount of trials needs to be
            % consistent)
            numLayers = length(chstimuli(stimuliIndex).layers);
            % Loop over all layers)
            for layer = 1:numLayers
                % Get layer data and current amount of trials
                data          = chstimuli(stimuliIndex).layers(layer).data;
                layerSettings = chstimuli(stimuliIndex).layers(layer).settings;
                [~, C]        = size(data);
            
                if userRuns > C % Add more columns if more trials than previous
                    for c = 1:(userRuns-C)
                        data(:, C+c)     = data(:, C);
                        layerSettings(:, C+c) = layerSettings(:, C);
                    end
                elseif userRuns < C && userRuns > 0 % Remove columns if less than previous
                    for c = 1:(C-userRuns)
                        data(:, C-c+1)     = [];
                        layerSettings(:, C-c+1) = [];
                    end
                end
                % Don't do anything if columns is the same as current
                
                % Update app settings with changes
                chstimuli(stimuliIndex).layers(layer).data     = data;
                chstimuli(stimuliIndex).layers(layer).settings = layerSettings;
            end
            
            % Update GUI and set app data
            setappdata(0, 'chstimuli', chstimuli);
            updateTable(app);
        end

        % Menu selected function: openFile
        function openFile_Callback(app, event)
            % Opens user saved stimulus file and refreshes GUI
            menuFcn('Open');
            updateFigure(app);
        end

        % Button pushed function: previous
        function previous_Callback(app, event)
            % Selects previous stimulus in list
            navData   = getappdata(0, 'navData');    %navigation data
            index     = navData.activeStim;          %index of current gui
            
            gotoStim(app, index - 1)
        end

        % Button pushed function: resetMissed
        function resetMissed_Callback(app, event)
            % Executes on button press in resetMissed.
            % Resets counter for total missed frames

            navData = getappdata(0, 'navData');
            navData.skippedFrames = 0;
            disp('Total number of skipped frames reset');
            
            setappdata(0, 'navData', navData);
            updateTable(app);
        end

        % Button pushed function: rowLin
        function rowLin_Callback(app, event)
            % --- Executes on button press in rowLin.
            % Interpolate from start to stop values linearly
            if app.allRows.Value
                % Get relevant app data
                chstimuli = getappdata(0, 'chstimuli');
                navData   = getappdata(0, 'navData');
                % Get current stimulus and layer indexes
                stimulusIndex     = navData.activeStim;
                layerIndex        = navData.marker(stimulusIndex);
            
                % Get layer data and amount of rows
                data      = chstimuli(stimulusIndex).layers(layerIndex).data;
                [R, ~, ~] = size(data);
            
                % Perform linear interpolation across all rows
                for r = 1:R
                    rowInterp(app, r, 'LIN');
                end
            else
                % Do linear interpolation across a single row
                row = str2double(app.chosenRow.Value);
                rowInterp(app, row, 'LIN');
            end
            updateFigure(app);
        end

        % Button pushed function: rowLog
        function rowLog_Callback(app, event)
            % Interpolate from start to stop values in a row logarithmicly
            if app.allRows.Value
                % Get relevant app data
                chstimuli = getappdata(0, 'chstimuli');
                navData   = getappdata(0, 'navData');
                % Get current stimulus and layer indexes
                stimuliIndex = navData.activeStim;
                layerIndex   = navData.marker(stimuliIndex);
            
                % Get layer data and amount of rows
                data      = chstimuli(stimuliIndex).layers(layerIndex).data;
                [R, ~, ~] = size(data);
                % Add log values across all rows
                for r = 1:R
                    rowInterp(app, r, 'LOG');
                end
            else
                % Add log values across single row
                row = str2double(app.chosenRow.Value);
                rowInterp(app, row, 'LOG');
            end
            % Update GUI
            updateFigure(app);
        end

        % Button pushed function: rowShuffle_1
        function rowShuffle_Callback(app, event)
            % Executes on button press in rowShuffle.

            % Shuffle across all rows if selected
            if app.allRows.Value
                % Get  useful app data
                chstimuli = getappdata(0, 'chstimuli');
                navData   = getappdata(0, 'navData');

                % Get pointers to active stimulus
                stimuliIndex = navData.activeStim;
                layerIndex   = navData.marker(stimuliIndex);
            
                % Get layer data and the amount of rows in it
                data      = chstimuli(stimuliIndex).layers(layerIndex).data;
                [R, ~, ~] = size(data);
            
                for r = 1:R
                    rowShuffle(app, r);
                end
            else
                % Get selected row and shuffles all values in it
                row = str2double(app.chosenRow.Value);
                rowShuffle(app, row);
            end
            % Update changes to GUI
            updateFigure(app);
        end

        % Button pushed function: run
        function run_Callback(app, event)
            % Executes when user clicks on the 'run' button to display
            % trial stimuli

            % Get current user settings
            navData    = getappdata(0, 'navData');
            screenData = getappdata(0, 'screenData');
            chstimuli  = getappdata(0, 'chstimuli');
            % Get pointer to current stimuli
            index      = navData.activeStim;
            
            % Save current bg selection prior to running through trials
            screenData.beforeBgColor = screenData.bgColor;
            
            if ~screenData.isInit % if screen is not init, initialize and close after run
                disp(' ');
                disp('Initilizing screen for single experiment...')
                screenFcn('Init');
                screenData  = getappdata(0, 'screenData');
                % Boolean to turn screen off after experiment or not
                closeScreen = 1;
            else
                closeScreen = 0;
            end
            
            % Screen is now in use, update screenData and figure
            screenData.inUse = 1;
            screenData.useSplitScreen = app.splitScreen.Value;
            setappdata(0, 'screenData', screenData);
            updateFigure(app);

            % Reset interrupt status
            cancelInterrupt = false;
            setappdata(0, 'cancelInterrupt', cancelInterrupt);
            
            % Get number of trials
            [~, C]  = size(chstimuli(index).layers(1).data); % Same number of trials in all layers
            
            % Use only a portion of the trials if the trialSubset setting
            % is enabled
            if app.trialSubset.Value
                trialChoice = app.trials.Value;
                % If no trial subset choice, use all
                if isempty(trialChoice)
                    trialChoice = 1:C;
                else
                    trialChoice = eval(strrep(trialChoice,'end',num2str(C)));
                    % Automatically clip values larger than the number of trials
                    trialChoice = min(C,trialChoice);
                end
            % Otherwise, use all trials
            else
                trialChoice = 1:C;
            end

            % --RUN STIMULUS HERE-- (Script found in 'subfunctions')
            app.CancelButton.Enable = 'on';
            [missed, cancelled] = animationLoop(chstimuli(index), screenData, navData, trialChoice);
            app.CancelButton.Enable = 'off';

            if cancelled == 1
                disp("Stimuli cancelled by user")
            else
                % Store how many frames were missed whilst running the
                % animation (For this both this run and all runs)
                navData.skippedLatest = missed;
                navData.skippedFrames = navData.skippedFrames + missed;
            end

            % Save current application state
            screenData.bgColor = chstimuli(index).targetBgColor;
            screenData.inUse   = 0;

            setappdata(0, 'screenData', screenData);
            setappdata(0, 'navData',    navData);
            
            % Update gui
            updateFigure(app);

            % Reset interrupt status
            cancelInterrupt = false;
            setappdata(0, 'cancelInterrupt', cancelInterrupt);
            
            % If screen was initialised via run button, kill screen
            if closeScreen
                screenFcn('Kill');
            end
        end

        % Menu selected function: saveAs
        function saveAs_Callback(app, event)
            % Saves all user data including settings, stimuli setups, and
            % lost frame data, and updates the gui depending on the name of
            % the file saved.

            % Points to a script in the 'subfunctions' folder
            menuFcn('SaveAs');
            % Update GUI
            updateTable(app);
        end

        % Menu selected function: settings
        function settings_Callback(app, event)
            % Opens the flyfly settings window on press
            mainSettings;
        end

        % Button pushed function: shiftColLeft
        function shiftColLeft_Callback(app, event)
            % Executes on button press in shiftColLeft, moves currently
            % selected trial to the left
            shiftColumn(app, -1);
        end

        % Button pushed function: shiftColRight
        function shiftColRight_Callback(app, event)
            % Executes on button press in shiftColRight, moves currently
            % selected trial to the right
            shiftColumn(app, 1);
        end

        % Value changed function: stimList
        function stimList_Callback(app, event)
            % Executes on selection change in stimList i.e. whenever the
            % user changes their current layer
            
            % Get new selection and update the gui
            stim = event.Value; %index of chosen stim
            gotoStim(app, stim)
        end

        % Button pushed function: stimSettings
        function stimSettings_Callback(app, event)
            % Opens the layer settings gui to configure specific stimulus
            % properties (when applicable)
            layerSettings;
        end

        % Value changed function: trialSubset
        function trialSubset_Callback(app, event)
            % Executes when trialSubset button pressed.
            % Toggles interactibility with a setting depending on user choice
            
            if event.Value % If tickbox is checked, enable trial subsets
                app.trials.Enable = 'On';
            else           % Otherwise disable the setting
                app.trials.Enable = 'Off';
            end
        end

        % Cell edit callback: uitable1
        function uitable1_CellEditCallback(app, event)
            % Executes when user edits data in the stimuli table 
            % Obtain user settings
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            
            % Get pointer to current workspace
            stimIndex     = navData.activeStim;
            currentLayer  = navData.marker(stimIndex);
            
            % Get data that user just edited
            newData       = app.uitable1.Data;

            % Store that data in user settings
            chstimuli(stimIndex).layers(currentLayer).data = newData;
            setappdata(0, 'chstimuli', chstimuli);
        end

        % Cell selection callback: uitable1
        function uitable1_CellSelectionCallback(app, event)
            % Executes when selected cell(s) is changed in the stimuli table.
            index = event.Indices;
            
            % If the user clicks outside of any of the cell values, return
            if isempty(index)
                return
            end
            
            % Update the users selected cell in the GUI
            app.chosenRow.Value = num2str(index(1));
            app.chosenCol.Value = num2str(index(2));
        end

        % Key press function: uitable1
        function uitable1KeyPress(app, event)
            % Paste data into sequencer table
            if length(event.Modifier) == 1 && strcmp(event.Modifier{:},'control') && ... 
                    strcmp(event.Key,'v')
                import = importdata('-pastespecial');
            
                chstimuli = getappdata(0, 'chstimuli');
                navData   = getappdata(0, 'navData');
            
                index    = navData.activeStim;
                z        = navData.marker(index);
            
                data     = chstimuli(index).layers(z).data;
            
                selectedRow = str2double(app.chosenRow.Value);
                selectedCol = str2double(app.chosenCol.Value);
            
                [r, c] = size(import);   % size of data in clipboard
                [R, C] = size(data);     % size of current data
            
                endRow = selectedRow+r-1;
                endRow = min(R,endRow);
            
                endCol = selectedCol+c-1;
                endCol = min(C,endCol);
            
                for i = selectedRow:endRow
                    for j = selectedCol:endCol
                        value = import(i-selectedRow+1, j-selectedCol+1);
                        if isnumeric(value)
                            data(i,j) = value;
                        end
                    end
                end
            
                chstimuli(index).layers(z).data = data;
                setappdata(0, 'chstimuli', chstimuli);
            
            end
            updateFigure(app);
        end

        % Button pushed function: useValue1
        function useValue1_Callback(app, event)
            % Executes on button press in useValue.
            % Applies the selected number to all trials in that row
            
            % Get user settings
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            
            % Get selected row and column
            row = str2double(app.chosenRow.Value);
            col = str2double(app.chosenCol.Value);
            
            % numVal represents every nth trial to copy data to
            % i.e. if numVal = 1, copy data over to every trial, if it = 2,
            % copy data to every 2nd trial.
            numVal = str2double(app.valNum.Value);
            
            % Grab settings for current stimulus and layer
            stimIndex    = navData.activeStim;
            layer        = navData.marker(stimIndex);
            layerData    = chstimuli(stimIndex).layers(layer).data;
            
            % Get amount of columns in table (amount of trials)
            [~, C] = size(layerData);

            % If all rows checkbox is ticked, copy all trial data over to
            % other trials
            if app.allRows.Value
                % Loop over every trial column, for every nth trial
                % (numVal), copy over data
                for c = 1:C
                    if mod(c-col, numVal) == 0
                        layerData(:,c) = layerData(:,col);
                    end
                end
            % If all rows is not checked, copy over data from a single cell
            % for every nth trial
            else
                for c = 1:C
                    if mod(c-col, numVal) == 0
                        layerData(row,c) = layerData(row,col);
                    end
                end
            end
            
            % Save user stimuli settings and update the gui
            chstimuli(stimIndex).layers(layer).data  = layerData;
            setappdata(0, 'chstimuli', chstimuli);
            updateTable(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            cancelInterrupt = true;
            setappdata(0, 'cancelInterrupt', cancelInterrupt);
        end

        % Value changed function: splitScreen
        function splitScreenValueChanged(app, event)
            value = app.splitScreen.Value;
            if value == 1
                app.splitScreenDir.Enable = "on";
                app.splitScreenPos.Enable = "on";
            else
                app.splitScreenDir.Enable = "off";
                app.splitScreenPos.Enable = "off";
            end
            screenData = getappdata(0, 'screenData');
            screenData.useSplitScreen = value;
            setappdata(0, 'screenData', screenData);
        end

        % Value changed function: splitScreenPos
        function splitScreenPosValueChanged(app, event)
            screenData = getappdata(0, 'screenData');
            value = app.splitScreenPos.Value;
            if app.splitScreenDir.Value == "Vertically"
                maxPixel = screenData.partial(3);
            else
                maxPixel = screenData.partial(4);
            end
            if value < 0 || value > maxPixel % Please update this to account for eithervertical or horizontal split
                disp("Desired size is not possible on your current screen setup!")
                app.splitScreenPos.Value = screenData.splitPos;
                return
            end
            screenData.splitPos = value;
            setappdata(0, 'screenData', screenData);
        end

        % Value changed function: splitScreenDir
        function splitScreenDirValueChanged(app, event)
            screenData = getappdata(0, 'screenData');
            value = app.splitScreenDir.Value;
            % Sets value as either 'Vertically' or 'Horizontally'
            screenData.splitDir = value;
            setappdata(0, 'screenData', screenData);
        end

        % Button pushed function: initBackground
        function initBackgroundButtonPushed(app, event)
            screenData = getappdata(0, 'screenData');

            if screenData.backgroundInit == 0
                screenFcn('initBackground');
            else
                disp("Background already initialised")
            end
        end

        % Button pushed function: closeBackground
        function closeBackgroundButtonPushed(app, event)
            screenData = getappdata(0, 'screenData');

            if screenData.backgroundInit == 1
                screenFcn('closeBackground');
            else
                disp("Background not open")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'tableGui';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.Tag = 'figure1';
            app.file = uimenu(app.figure1);
            app.file.Text = 'File';
            app.file.Tag = 'file';
            app.new = uimenu(app.file);
            app.new.MenuSelectedFcn = createCallbackFcn(app, @new_Callback, true);
            app.new.Accelerator = 'N';
            app.new.Text = 'New';
            app.new.Tag = 'new';
            app.openFile = uimenu(app.file);
            app.openFile.MenuSelectedFcn = createCallbackFcn(app, @openFile_Callback, true);
            app.openFile.Accelerator = 'O';
            app.openFile.Text = 'Open';
            app.openFile.Tag = 'open';
            app.saveAs = uimenu(app.file);
            app.saveAs.MenuSelectedFcn = createCallbackFcn(app, @saveAs_Callback, true);
            app.saveAs.Accelerator = 'S';
            app.saveAs.Text = 'Save as...';
            app.saveAs.Tag = 'saveAs';
            app.importStim = uimenu(app.file);
            app.importStim.MenuSelectedFcn = createCallbackFcn(app, @importStim_Callback, true);
            app.importStim.Separator = 'on';
            app.importStim.Accelerator = 'I';
            app.importStim.Text = 'Import Stimulus';
            app.importStim.Tag = 'importStim';
            app.exit = uimenu(app.file);
            app.exit.MenuSelectedFcn = createCallbackFcn(app, @exit_Callback, true);
            app.exit.Separator = 'on';
            app.exit.Text = 'Exit';
            app.exit.Tag = 'exit';
            app.tools = uimenu(app.figure1);
            app.tools.Text = 'Tools';
            app.tools.Tag = 'tools';
            app.recordStimulus = uimenu(app.tools);
            app.recordStimulus.Accelerator = 'R';
            app.recordStimulus.Text = 'Record stimulus';
            app.recordStimulus.Tag = 'recordStimulus';
            app.avimovie = uimenu(app.recordStimulus);
            app.avimovie.MenuSelectedFcn = createCallbackFcn(app, @avimovie_Callback, true);
            app.avimovie.Text = 'AVI movie';
            app.avimovie.Tag = 'avimovie';
            app.imsequence = uimenu(app.recordStimulus);
            app.imsequence.MenuSelectedFcn = createCallbackFcn(app, @imsequence_Callback, true);
            app.imsequence.Text = 'Image sequence';
            app.imsequence.Tag = 'imsequence';
            app.settings = uimenu(app.tools);
            app.settings.MenuSelectedFcn = createCallbackFcn(app, @settings_Callback, true);
            app.settings.Separator = 'on';
            app.settings.Text = 'Settings';
            app.settings.Tag = 'settings';
            app.uipanel13 = uipanel(app.figure1);
            app.uipanel13.Tag = 'uipanel13';
            app.previous = uibutton(app.uipanel13, 'push');
            app.previous.ButtonPushedFcn = createCallbackFcn(app, @previous_Callback, true);
            app.previous.Tag = 'previous';
            app.previous.Text = 'Previous';
            app.next = uibutton(app.uipanel13, 'push');
            app.next.ButtonPushedFcn = createCallbackFcn(app, @next_Callback, true);
            app.next.Tag = 'next';
            app.next.Text = 'Next';
            app.run = uibutton(app.uipanel13, 'push');
            app.run.ButtonPushedFcn = createCallbackFcn(app, @run_Callback, true);
            app.run.Tag = 'run';
            app.run.Text = 'RUN';
            app.initScreen = uibutton(app.uipanel13, 'push');
            app.initScreen.ButtonPushedFcn = createCallbackFcn(app, @initScreen_Callback, true);
            app.initScreen.Tag = 'initScreen';
            app.initScreen.Text = 'Init Screen';
            app.killScreen = uibutton(app.uipanel13, 'push');
            app.killScreen.ButtonPushedFcn = createCallbackFcn(app, @killScreen_Callback, true);
            app.killScreen.Tag = 'killScreen';
            app.killScreen.Enable = 'off';
            app.killScreen.Text = 'Kill Screen';
            app.grid = uibutton(app.uipanel13, 'push');
            app.grid.ButtonPushedFcn = createCallbackFcn(app, @grid_Callback, true);
            app.grid.Tag = 'grid';
            app.grid.Text = 'Grid';
            app.clear = uibutton(app.uipanel13, 'push');
            app.clear.ButtonPushedFcn = createCallbackFcn(app, @clear_Callback, true);
            app.clear.Tag = 'clear';
            app.clear.Text = 'Clear';
            app.stimList = uidropdown(app.uipanel13);
            app.stimList.Items = {'Pop-up Menu'};
            app.stimList.ValueChangedFcn = createCallbackFcn(app, @stimList_Callback, true);
            app.stimList.Tag = 'stimList';
            app.stimList.Value = 'Pop-up Menu';
            app.editBgColor = uieditfield(app.uipanel13, 'text');
            app.editBgColor.ValueChangedFcn = createCallbackFcn(app, @editBgColor_Callback, true);
            app.editBgColor.Tag = 'editBgColor';
            app.editBgColor.Value = '100';
            app.applyBgColour = uibutton(app.uipanel13, 'push');
            app.applyBgColour.ButtonPushedFcn = createCallbackFcn(app, @applyBgColour_Callback, true);
            app.applyBgColour.Tag = 'applyBgColour';
            app.applyBgColour.Text = 'Apply';
            app.text36 = uilabel(app.uipanel13);
            app.text36.Tag = 'text36';
            app.text36.Text = 'Background Colour';
            app.CancelButton = uibutton(app.uipanel13, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Enable = 'off';
            app.CancelButton.Text = 'Cancel';
            app.uipanel16 = uipanel(app.figure1);
            app.uipanel16.Tag = 'uipanel16';
            app.info = uilabel(app.uipanel16);
            app.info.Tag = 'info';
            app.info.Text = {'Screen:'; 'Missed frames:'; 'Total:'};
            app.resetMissed = uibutton(app.uipanel16, 'push');
            app.resetMissed.ButtonPushedFcn = createCallbackFcn(app, @resetMissed_Callback, true);
            app.resetMissed.Tag = 'resetMissed';
            app.resetMissed.Text = 'Reset';
            app.text17 = uilabel(app.uipanel16);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Info';
            app.stimName = uilabel(app.uipanel16);
            app.stimName.Tag = 'stimName';
            app.stimName.Text = 'tableGui';
            app.editName = uibutton(app.uipanel16, 'push');
            app.editName.ButtonPushedFcn = createCallbackFcn(app, @editName_Callback, true);
            app.editName.Tag = 'editName';
            app.editName.Text = 'Edit';
            app.layerName = uilabel(app.uipanel16);
            app.layerName.Tag = 'layerName';
            app.layerName.Text = 'subtitle';
            app.openLayerManager = uibutton(app.uipanel16, 'push');
            app.openLayerManager.ButtonPushedFcn = createCallbackFcn(app, @openLayerManager_Callback, true);
            app.openLayerManager.Tag = 'layerManager';
            app.openLayerManager.Text = 'Layer manager';
            app.layerList = uidropdown(app.uipanel16);
            app.layerList.Items = {'Pop-up Menu'};
            app.layerList.ValueChangedFcn = createCallbackFcn(app, @layerList_Callback, true);
            app.layerList.Tag = 'layerList';
            app.layerList.Value = 'Pop-up Menu';
            app.editLayerName = uibutton(app.uipanel16, 'push');
            app.editLayerName.ButtonPushedFcn = createCallbackFcn(app, @editLayerName_Callback, true);
            app.editLayerName.Tag = 'editLayerName';
            app.editLayerName.Text = 'Edit';
            app.stimSettings = uibutton(app.uipanel16, 'push');
            app.stimSettings.ButtonPushedFcn = createCallbackFcn(app, @stimSettings_Callback, true);
            app.stimSettings.Tag = 'stimSettings';
            app.stimSettings.Text = 'Layer settings';
            app.impulse = uicheckbox(app.uipanel16);
            app.impulse.ValueChangedFcn = createCallbackFcn(app, @impulse_Callback, true);
            app.impulse.Tag = 'impulse';
            app.impulse.Tooltip = 'If this is checked, movement will be calculated only for the first frame of each trial.';
            app.impulse.Text = 'Impulse';
            app.uitable1 = uitable(app.figure1);
            app.uitable1.ColumnName = {'Start'; 'Stop'};
            app.uitable1.ColumnRearrangeable = 'on';
            app.uitable1.RowName = {'Error'; ''};
            app.uitable1.ColumnEditable = [true true];
            app.uitable1.CellEditCallback = createCallbackFcn(app, @uitable1_CellEditCallback, true);
            app.uitable1.CellSelectionCallback = createCallbackFcn(app, @uitable1_CellSelectionCallback, true);
            app.uitable1.Tag = 'uitable1';
            app.uitable1.KeyPressFcn = createCallbackFcn(app, @uitable1KeyPress, true);
            app.TabGroup = uitabgroup(app.figure1);
            app.StandardtoolsTab = uitab(app.TabGroup);
            app.StandardtoolsTab.Title = 'Standard tools';
            app.uipanel1 = uipanel(app.StandardtoolsTab);
            app.uipanel1.Title = 'Sequencer';
            app.uipanel1.Tag = 'uipanel1';
            app.trials = uieditfield(app.uipanel1, 'text');
            app.trials.Tag = 'trials';
            app.trials.Enable = 'off';
            app.trials.Value = '1:end';
            app.trialSubset = uicheckbox(app.uipanel1);
            app.trialSubset.ValueChangedFcn = createCallbackFcn(app, @trialSubset_Callback, true);
            app.trialSubset.Tag = 'trialSubset';
            app.trialSubset.Text = ' Trial Subset';
            app.numRunsOk = uibutton(app.uipanel1, 'push');
            app.numRunsOk.ButtonPushedFcn = createCallbackFcn(app, @numRunsOk_Callback, true);
            app.numRunsOk.Tag = 'numRunsOk';
            app.numRunsOk.Text = 'OK';
            app.numRuns = uieditfield(app.uipanel1, 'text');
            app.numRuns.Tag = 'numRuns';
            app.numRuns.Value = '2';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Number of Trials:';
            app.uipanel2 = uipanel(app.StandardtoolsTab);
            app.uipanel2.Title = 'Interpolate';
            app.uipanel2.Tag = 'uipanel2';
            app.allRows = uicheckbox(app.uipanel2);
            app.allRows.Tag = 'allRows';
            app.allRows.Text = 'All rows';
            app.text10 = uilabel(app.uipanel2);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Row/Col';
            app.rowLin = uibutton(app.uipanel2, 'push');
            app.rowLin.ButtonPushedFcn = createCallbackFcn(app, @rowLin_Callback, true);
            app.rowLin.Tag = 'rowLin';
            app.rowLin.Text = 'Lin';
            app.shiftColRight = uibutton(app.uipanel2, 'push');
            app.shiftColRight.ButtonPushedFcn = createCallbackFcn(app, @shiftColRight_Callback, true);
            app.shiftColRight.Tag = 'shiftColRight';
            app.shiftColRight.Tooltip = 'Shift column to the right';
            app.shiftColRight.Text = '>>';
            app.shiftColLeft = uibutton(app.uipanel2, 'push');
            app.shiftColLeft.ButtonPushedFcn = createCallbackFcn(app, @shiftColLeft_Callback, true);
            app.shiftColLeft.Tag = 'shiftColLeft';
            app.shiftColLeft.Tooltip = 'Shift column to the left';
            app.shiftColLeft.Text = '<<';
            app.valNum = uieditfield(app.uipanel2, 'text');
            app.valNum.Tag = 'valNum';
            app.valNum.Value = '1';
            app.chosenCol = uieditfield(app.uipanel2, 'text');
            app.chosenCol.Tag = 'chosenCol';
            app.chosenCol.Editable = 'off';
            app.chosenCol.Value = '1';
            app.useValue1 = uibutton(app.uipanel2, 'push');
            app.useValue1.ButtonPushedFcn = createCallbackFcn(app, @useValue1_Callback, true);
            app.useValue1.Tag = 'useValue1';
            app.useValue1.Text = 'Use value';
            app.rowShuffle_1 = uibutton(app.uipanel2, 'push');
            app.rowShuffle_1.ButtonPushedFcn = createCallbackFcn(app, @rowShuffle_Callback, true);
            app.rowShuffle_1.Tag = 'rowShuffle_1';
            app.rowShuffle_1.Text = 'Shuffle';
            app.rowLog = uibutton(app.uipanel2, 'push');
            app.rowLog.ButtonPushedFcn = createCallbackFcn(app, @rowLog_Callback, true);
            app.rowLog.Tag = 'rowLog';
            app.rowLog.Text = 'Log';
            app.chosenRow = uieditfield(app.uipanel2, 'text');
            app.chosenRow.Tag = 'chosenRow';
            app.chosenRow.Editable = 'off';
            app.chosenRow.Value = '1';
            app.closeBackground = uibutton(app.StandardtoolsTab, 'push');
            app.closeBackground.ButtonPushedFcn = createCallbackFcn(app, @closeBackgroundButtonPushed, true);
            app.closeBackground.Tag = 'initScreen';
            app.closeBackground.Text = 'Close Background';
            app.initBackground = uibutton(app.StandardtoolsTab, 'push');
            app.initBackground.ButtonPushedFcn = createCallbackFcn(app, @initBackgroundButtonPushed, true);
            app.initBackground.Tag = 'initScreen';
            app.initBackground.Text = 'Init Background';
            app.AdvancedtoolsTab = uitab(app.TabGroup);
            app.AdvancedtoolsTab.Title = 'Advanced tools';
            app.uipanel23 = uipanel(app.AdvancedtoolsTab);
            app.uipanel23.Title = 'Paloma Target';
            app.uipanel23.Tag = 'uipanel23';
            app.generateXYbutton = uibutton(app.uipanel23, 'push');
            app.generateXYbutton.ButtonPushedFcn = createCallbackFcn(app, @generateXYbutton_Callback, true);
            app.generateXYbutton.Tag = 'generateXYbutton';
            app.generateXYbutton.Text = 'Generate XY';
            app.uipanel22 = uipanel(app.AdvancedtoolsTab);
            app.uipanel22.Title = 'Gaussian White Noise';
            app.uipanel22.Tag = 'uipanel22';
            app.text35 = uilabel(app.uipanel22);
            app.text35.Tag = 'text35';
            app.text35.Text = 'Variance';
            app.gaussianWhiteNoiseVariance = uieditfield(app.uipanel22, 'text');
            app.gaussianWhiteNoiseVariance.Tag = 'gaussianWhiteNoiseVariance';
            app.gaussianWhiteNoiseVariance.Value = '0';
            app.applyWhiteNoiseBtn = uibutton(app.uipanel22, 'push');
            app.applyWhiteNoiseBtn.ButtonPushedFcn = createCallbackFcn(app, @applyWhiteNoiseBtn_Callback, true);
            app.applyWhiteNoiseBtn.Tag = 'applyWhiteNoiseBtn';
            app.applyWhiteNoiseBtn.Text = 'APPLY';
            app.uipanel18 = uipanel(app.AdvancedtoolsTab);
            app.uipanel18.Title = 'm-sequence';
            app.uipanel18.Tag = 'uipanel18';
            app.useCumsum = uicheckbox(app.uipanel18);
            app.useCumsum.Tag = 'useCumsum';
            app.useCumsum.Text = ' cumsum';
            app.mseqStart = uieditfield(app.uipanel18, 'text');
            app.mseqStart.Tag = 'mseqStart';
            app.mseqStart.Value = '100';
            app.mseqChange = uieditfield(app.uipanel18, 'text');
            app.mseqChange.Tag = 'mseqChange';
            app.mseqChange.Value = '10';
            app.mseqButton = uibutton(app.uipanel18, 'push');
            app.mseqButton.ButtonPushedFcn = createCallbackFcn(app, @mseqButton_Callback, true);
            app.mseqButton.Tag = 'mseq';
            app.mseqButton.Text = 'MSeq';
            app.SplitScreenexperimentalFeaturePanel = uipanel(app.AdvancedtoolsTab);
            app.SplitScreenexperimentalFeaturePanel.Title = 'Split Screen (experimental Feature)';
            app.splitScreen = uicheckbox(app.SplitScreenexperimentalFeaturePanel);
            app.splitScreen.ValueChangedFcn = createCallbackFcn(app, @splitScreenValueChanged, true);
            app.splitScreen.Text = 'Use split screen';
            app.SplitatpixelEditFieldLabel = uilabel(app.SplitScreenexperimentalFeaturePanel);
            app.SplitatpixelEditFieldLabel.Enable = 'off';
            app.SplitatpixelEditFieldLabel.Text = 'Split at pixel:';
            app.splitScreenPos = uieditfield(app.SplitScreenexperimentalFeaturePanel, 'numeric');
            app.splitScreenPos.ValueChangedFcn = createCallbackFcn(app, @splitScreenPosValueChanged, true);
            app.splitScreenPos.Enable = 'off';
            app.splitScreenPos.Value = 960;
            app.SplitScreenDropDownLabel = uilabel(app.SplitScreenexperimentalFeaturePanel);
            app.SplitScreenDropDownLabel.Enable = 'off';
            app.SplitScreenDropDownLabel.Text = 'Split Screen';
            app.splitScreenDir = uidropdown(app.SplitScreenexperimentalFeaturePanel);
            app.splitScreenDir.Items = {'Vertically', 'Horizontally'};
            app.splitScreenDir.ValueChangedFcn = createCallbackFcn(app, @splitScreenDirValueChanged, true);
            app.splitScreenDir.Enable = 'off';
            app.splitScreenDir.Value = 'Vertically';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tableGui(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)tableGui_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end